classdef configuration_manager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigurationManagerUIFigure  matlab.ui.Figure
        SaveConfigButton              matlab.ui.control.Button
        ZonesPanel                    matlab.ui.container.Panel
        AddzoneButton                 matlab.ui.control.Button
        CurrentZonesListBoxLabel      matlab.ui.control.Label
        CurrentZonesListBox           matlab.ui.control.ListBox
        deletezoneButton              matlab.ui.control.Button
        pretimemsEditFieldLabel       matlab.ui.control.Label
        pretimems_ed                  matlab.ui.control.NumericEditField
        posttimemsEditFieldLabel      matlab.ui.control.Label
        posttimems_ed                 matlab.ui.control.NumericEditField
        new_zone                      matlab.ui.control.EditField
        renamezoneButton              matlab.ui.control.Button
        error_zones                   matlab.ui.control.Label
        ConceptsPanel                 matlab.ui.container.Panel
        deleteconceptButton           matlab.ui.control.Button
        CurrentConceptsListBoxLabel   matlab.ui.control.Label
        CurrentConceptsListBox        matlab.ui.control.ListBox
        AddconceptButton              matlab.ui.control.Button
        new_concept                   matlab.ui.control.EditField
        renameconceptButton           matlab.ui.control.Button
        error_concepts                matlab.ui.control.Label
        LoadConfigButton              matlab.ui.control.Button
        ApplyConfigButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        concepts
        zones
        par
        auna_hObject
    end
    
    methods (Access = private)
        
        function clean_erros(app)
            app.error_zones.Text = '';
            app.error_concepts.Text = '';
        
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, auna_gui)
            handles = guidata(auna_gui);
            app.auna_hObject = auna_gui;
            app.par = handles.par;
            app.zones = handles.zones;
            app.concepts = handles.concepts;
            app.CurrentZonesListBox.Items = fieldnames(app.zones);
            app.CurrentConceptsListBox.Items = fieldnames(app.concepts);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            app.clean_erros();
            [file,path] = uigetfile('*.mat','Select matfile with auna data');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            filename = [path filesep file];
            load(filename, 'concepts','zones');
            app.concepts = concepts;
            app.zones = zones;
        end

        % Button pushed function: ApplyConfigButton
        function ApplyConfigButtonPushed(app, event)
            app.clean_erros();
            handles = guidata(app.auna_hObject);
            handles.concepts = app.concepts;
            handles.zones = app.zones;
            guidata(app.auna_hObject, handles);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            app.clean_erros();
            concepts = app.concepts;
            zones = app.zones;
            uisave({'concepts','zones'}, 'auna_data.mat')            
        end

        % Button pushed function: renamezoneButton
        function renamezoneButtonPushed(app, event)
            app.clean_erros();
            field2rename = app.CurrentZonesListBox.Value;
            if ~isempty(app.new_zone.Value)
                if isfield( app.zones, app.new_zone.Value)
                    app.error_zones.Text = 'Name already exists';
                else
                app.zones.(app.new_zone.Value) = app.zones.(field2rename); 
                app.zones = rmfield( app.zones , field2rename );
                end
            else
                app.error_zones.Text = 'Empty string as new name';
            end
            app.CurrentZonesListBox.Items = fieldnames(app.zones)
            app.CurrentZonesListBoxValueChanged([]);
        end

        % Value changed function: CurrentZonesListBox
        function CurrentZonesListBoxValueChanged(app, event)
            
            current_zone = app.CurrentZonesListBox.Value;
            app.CurrentZonesListBox.Value = current_zone;
            app.pretimems_ed.Value = app.zones.(current_zone).pre_time;
            app.posttimems_ed.Value = app.zones.(current_zone).post_time;
            
        end

        % Button pushed function: renameconceptButton
        function renameconceptButtonPushed(app, event)
            app.clean_erros();
            field2rename = app.CurrentConceptsListBox.Value;
            if ~isempty(app.new_concept.Value)
                if isfield( app.concepts, app.new_concept.Value)
                    app.error_concepts.Text = 'Name already exists';
                else
                    app.concepts.(app.new_concept.Value) = app.concepts.(field2rename); 
                    app.concepts = rmfield( app.concepts , field2rename );
                end
            else
                app.error_concepts.Text = 'Empty string as new name';
            end
            app.CurrentConceptsListBox.Items = fieldnames(app.concepts)
        end

        % Button pushed function: AddzoneButton
        function AddzoneButtonPushed(app, event)
            app.clean_erros();
            if isfield( app.zones, app.new_zone.Value)
                app.error_zones.Text = 'Name already exists';
            else
                aux_zone = struct;
                aux_zone.limits = [nan,nan];
                aux_zone.pre_time = app.par.default_pre_time_ms;
                aux_zone.post_time = app.par.default_post_time_ms;
                app.zones.(app.new_zone.Value) = aux_zone;
            end
        
            app.CurrentZonesListBox.Items = fieldnames(app.zones);
            app.CurrentZonesListBox.Value = app.new_zone.Value;
            app.CurrentZonesListBoxValueChanged([]);
        end

        % Button pushed function: deletezoneButton
        function deletezoneButtonPushed(app, event)
            app.clean_erros();
            if isempty(app.CurrentZonesListBox.Value)
                app.error_zones.Text = 'Select the Zone to remove';
            else
                app.zones = rmfield( app.zones , app.CurrentZonesListBox.Value );
                app.CurrentZonesListBox.Items = fieldnames(app.zones);
            end
            
        end

        % Button pushed function: deleteconceptButton
        function deleteconceptButtonPushed(app, event)
            app.clean_erros();
            if isempty(app.CurrentConceptsListBox.Value)
                app.error_concepts.Text = 'Select the Concept to remove';
            else
            app.concepts = rmfield( app.concepts , app.CurrentConceptsListBox.Value );
            app.CurrentConceptsListBox.Items = fieldnames(app.concepts);
            end
        end

        % Button pushed function: AddconceptButton
        function AddconceptButtonPushed(app, event)
            app.clean_erros();
            if isfield( app.concepts, app.new_concept.Value)
                app.error_concepts.Text = 'Name already exists';
            else
                app.concepts.(app.new_concept.Value) = [];
                app.CurrentConceptsListBox.Items = fieldnames(app.concepts);
            end
            app.CurrentConceptsListBox.Value = app.new_concept.Value;

        end

        % Value changed function: pretimems_ed
        function pretimems_edValueChanged(app, event)
            value = app.pretimems_ed.Value;
            if ~isempty(app.CurrentConceptsListBox.Value)
                app.concepts.(app.CurrentConceptsListBox.Value).pre_time = value;
            end
        end

        % Value changed function: posttimems_ed
        function posttimems_edValueChanged(app, event)
            value = app.posttimems_ed.Value;
            if ~isempty(app.CurrentConceptsListBox.Value)
                app.concepts.(app.CurrentConceptsListBox.Value).post_time = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigurationManagerUIFigure = uifigure('Visible', 'off');
            app.ConfigurationManagerUIFigure.Name = 'Configuration Manager';
            app.SaveConfigButton = uibutton(app.ConfigurationManagerUIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.ZonesPanel = uipanel(app.ConfigurationManagerUIFigure);
            app.ZonesPanel.Title = 'Zones';
            app.AddzoneButton = uibutton(app.ZonesPanel, 'push');
            app.AddzoneButton.ButtonPushedFcn = createCallbackFcn(app, @AddzoneButtonPushed, true);
            app.AddzoneButton.Text = 'Add zone';
            app.CurrentZonesListBoxLabel = uilabel(app.ZonesPanel);
            app.CurrentZonesListBoxLabel.Text = 'Current Zones';
            app.CurrentZonesListBox = uilistbox(app.ZonesPanel);
            app.CurrentZonesListBox.Items = {};
            app.CurrentZonesListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentZonesListBoxValueChanged, true);
            app.CurrentZonesListBox.Value = {};
            app.deletezoneButton = uibutton(app.ZonesPanel, 'push');
            app.deletezoneButton.ButtonPushedFcn = createCallbackFcn(app, @deletezoneButtonPushed, true);
            app.deletezoneButton.Text = 'delete zone';
            app.pretimemsEditFieldLabel = uilabel(app.ZonesPanel);
            app.pretimemsEditFieldLabel.Text = 'pre time (ms)';
            app.pretimems_ed = uieditfield(app.ZonesPanel, 'numeric');
            app.pretimems_ed.Limits = [0 Inf];
            app.pretimems_ed.ValueChangedFcn = createCallbackFcn(app, @pretimems_edValueChanged, true);
            app.posttimemsEditFieldLabel = uilabel(app.ZonesPanel);
            app.posttimemsEditFieldLabel.Text = 'post time (ms)';
            app.posttimems_ed = uieditfield(app.ZonesPanel, 'numeric');
            app.posttimems_ed.Limits = [0 Inf];
            app.posttimems_ed.ValueChangedFcn = createCallbackFcn(app, @posttimems_edValueChanged, true);
            app.new_zone = uieditfield(app.ZonesPanel, 'text');
            app.renamezoneButton = uibutton(app.ZonesPanel, 'push');
            app.renamezoneButton.ButtonPushedFcn = createCallbackFcn(app, @renamezoneButtonPushed, true);
            app.renamezoneButton.Text = 'rename zone';
            app.error_zones = uilabel(app.ZonesPanel);
            app.error_zones.Text = '';
            app.ConceptsPanel = uipanel(app.ConfigurationManagerUIFigure);
            app.ConceptsPanel.Title = 'Concepts';
            app.deleteconceptButton = uibutton(app.ConceptsPanel, 'push');
            app.deleteconceptButton.ButtonPushedFcn = createCallbackFcn(app, @deleteconceptButtonPushed, true);
            app.deleteconceptButton.Text = 'delete concept';
            app.CurrentConceptsListBoxLabel = uilabel(app.ConceptsPanel);
            app.CurrentConceptsListBoxLabel.Text = 'Current Concepts';
            app.CurrentConceptsListBox = uilistbox(app.ConceptsPanel);
            app.CurrentConceptsListBox.Items = {};
            app.CurrentConceptsListBox.Value = {};
            app.AddconceptButton = uibutton(app.ConceptsPanel, 'push');
            app.AddconceptButton.ButtonPushedFcn = createCallbackFcn(app, @AddconceptButtonPushed, true);
            app.AddconceptButton.Text = 'Add concept';
            app.new_concept = uieditfield(app.ConceptsPanel, 'text');
            app.renameconceptButton = uibutton(app.ConceptsPanel, 'push');
            app.renameconceptButton.ButtonPushedFcn = createCallbackFcn(app, @renameconceptButtonPushed, true);
            app.renameconceptButton.Text = 'rename concept';
            app.error_concepts = uilabel(app.ConceptsPanel);
            app.error_concepts.Text = '';
            app.LoadConfigButton = uibutton(app.ConfigurationManagerUIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ApplyConfigButton = uibutton(app.ConfigurationManagerUIFigure, 'push');
            app.ApplyConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyConfigButtonPushed, true);
            app.ApplyConfigButton.Text = 'Apply Config';
            % Show the figure after all components are created
            app.ConfigurationManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configuration_manager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigurationManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigurationManagerUIFigure)
        end
    end
end