classdef raster_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RastermenuUIFigure          matlab.ui.Figure
        CreaterastersButton         matlab.ui.control.Button
        SeleectZonesListBoxLabel    matlab.ui.control.Label
        SelectZonesListBox          matlab.ui.control.ListBox
        SelectConceptsListBoxLabel  matlab.ui.control.Label
        SelectConceptsListBox       matlab.ui.control.ListBox
        output                      matlab.ui.control.Label
        mergeconceptsCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
            auna_handles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, auna_handles)
            app.auna_handles = auna_handles;
            app.SelectZonesListBox.Items = fieldnames(auna_handles.zones);
            app.SelectConceptsListBox.Items = fieldnames(auna_handles.concepts);
        end

        % Button pushed function: CreaterastersButton
        function CreaterastersButtonPushed(app, event)
            app.output.Text='';
            if isempty(app.SelectZonesListBox.Value)
                app.output.Text='Select at least one Zone';
                return
            end
            if isempty(app.SelectConceptsListBox.Value)
                app.output.Text='Select at least one Concept';
                return
            end            
            
            [file,path] = uiputfile('','Choose output image filename');
            if isequal(file,0) || isequal(path,0)
                return;
            end
            filename = [path filesep file];
            auna_rasters(app.auna_handles, app.SelectZonesListBox.Value,app.SelectConceptsListBox.Value,app.mergeconceptsCheckBox.Value,filename);
            app.output.Text='New files created';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RastermenuUIFigure = uifigure('Visible', 'off');
            app.RastermenuUIFigure.Name = 'Raster menu';
            app.CreaterastersButton = uibutton(app.RastermenuUIFigure, 'push');
            app.CreaterastersButton.ButtonPushedFcn = createCallbackFcn(app, @CreaterastersButtonPushed, true);
            app.CreaterastersButton.Text = 'Create rasters ';
            app.SeleectZonesListBoxLabel = uilabel(app.RastermenuUIFigure);
            app.SeleectZonesListBoxLabel.Text = 'Seleect Zones';
            app.SelectZonesListBox = uilistbox(app.RastermenuUIFigure);
            app.SelectZonesListBox.Items = {'', ''};
            app.SelectZonesListBox.Multiselect = 'on';
            app.SelectZonesListBox.Value = {''};
            app.SelectConceptsListBoxLabel = uilabel(app.RastermenuUIFigure);
            app.SelectConceptsListBoxLabel.Text = 'Select Concepts';
            app.SelectConceptsListBox = uilistbox(app.RastermenuUIFigure);
            app.SelectConceptsListBox.Items = {};
            app.SelectConceptsListBox.Multiselect = 'on';
            app.SelectConceptsListBox.Value = {};
            app.output = uilabel(app.RastermenuUIFigure);
            app.output.Text = '';
            app.mergeconceptsCheckBox = uicheckbox(app.RastermenuUIFigure);
            app.mergeconceptsCheckBox.Text = 'merge concepts';
            % Show the figure after all components are created
            app.RastermenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = raster_menu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RastermenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RastermenuUIFigure)
        end
    end
end